classdef myPrivacyPersona < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        StartTab                   matlab.ui.container.Tab
        StartButton                matlab.ui.control.Button
        ErrorLabel                 matlab.ui.control.Label
        SelectaDatasetButtonGroup  matlab.ui.container.ButtonGroup
        DefaultDSButton            matlab.ui.control.RadioButton
        FindDSButton               matlab.ui.control.RadioButton
        ShowDataset                matlab.ui.control.Label
        selectCustomDSButton       matlab.ui.control.Button
        selectCustomDSLabel        matlab.ui.control.Label
        WelcomeLabel               matlab.ui.control.Label
        RisultatiTab               matlab.ui.container.Tab
        RisultatiLabel             matlab.ui.control.Label
        FeatureSelButton           matlab.ui.control.Button
        ErroreFinaleLabel          matlab.ui.control.Label
        FeatureSelLabel            matlab.ui.control.Label
        FeatureSelNumberLabel      matlab.ui.control.Label
        ResultGraph                matlab.ui.control.UIAxes
        ImportantFeatureDeleted    matlab.ui.control.Label
        FindmypermissionTab        matlab.ui.container.Tab
        QuestionLabel              matlab.ui.control.Label
        NOButton                   matlab.ui.control.Button
        BOHButton                  matlab.ui.control.Button
        YESButton                  matlab.ui.control.Button
        QuestionLabel2             matlab.ui.control.Label
        counterQuestionLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        
        questions = ["condivideresti il tuo nome? ";
            "condivederesti il tuo cognome? ";
            "sei: ";
            "condivideresti il tuo compleanno? ";
            "condivideresti la tua altezza? ";
            "condivideresti il tuo peso? ";
            "condividi la tua identità con lo smartphone? ";
            "condividi i contatti con il tuo smartphone? ";
            "condividi la posizione con il tuo smartphone? ";
            "condividi i permessi agli sms con il tuo smartphone? ";
            "condividi i permessi alla memoria con il tuo smartphone? ";
            "condividi l'acceso alla camera con il tuo smartphone? ";
            "condividi l'accesso al bluetoot con il tuo smartphone? ";
            "condividi l'accesso alle tuo foto con il tuo smartphone? ";
            "condividi l'accesso al tuo smartphone con il tuo smartphone? ";
            "condividi motion con il tuo smartphone? ";
            "condividi l'accesso ai tuoi media con il tuo smartphone? ";
            "condividi l'accesso ai dati mobili con il tuo smartphone? ";
            "condivideresti il conto dei tuoi passi? ";
            "condivideresti la distanza che percorrei? ";
            "condivideresti la quota del tuo percorso? ";
            "condivideresti i floors? ";
            "condivideresti le tue attività ?";
            "condivideresti le calorie consumate ?";
            "condivideresti il tuo peso con l'app ?";
            "condivideresti quanto dormi? ";
            "condivideresti battito cardiaco registrato? ";
            "condivideresti le caloria assunte? ";
            "condivideresti la conoscenza dei tuoi amici? ";
            "condivideresti il tuo profilo? ";
            "condivideresti la tua posizione? ";
            "condivideresti i tuoi settaggi? ";
            "condivideresti sui social i tuoi traguardi pubblicamente? ";
            "condivideresti sui social i tuoi traguardi con gli amici? ";
            "condicideresti con health i tuoi traguardi? ";
            "condivideresti con un professionista i tuoi dati? ";
            "condivideresti con il governo i tuoi dati? ";
            "condivideresti con altre app installate sullo smartphone i tuoi dati? ";
            "condivideresti informazioni a scopo di migliorare la tua salute? ";
            "condivideresti informazioni a scopo di migliarare la tua sicurezza? ";
            "condivideresti informazioni a scopo di migliorare la tua parte social? ";
            "condivideresti infomrazioni a scopo commerciale? ";
            "condicideresti informazioni a scopo di ricevere sconti mirati? ";
            "con quale frequenza vuoi che questa app possa accedere ai tuoi dati? ";
            "come vorresti che questa app gestisse i tuoi dati salvati? "];%contiene le domande per le features
        
        
        answeres = ["no" "yes" " ";
            "no" "yes" " ";
            "man" "woman" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "no" "yes" " ";
            "only when using app" "separate permission" "cont access";
            "indefinitly" "if app is installed" "untill necessary"];%contiene le possibili risposte per le domande precedenti
        
        
        meanings = [1 2 -1;
            1 2 -1;
            1 2 -1;
            1 2 -1;
            1 2 -1;
            1 2 -1;
            1 2 -1;
            1 2 -1;
            1 2 -1;
            1 2 -1;
            1 2 -1;
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            1 2 3;
            1 2 3];%contiene il significato corrispondente nel dataset delle risposte precedenti
        
    end
    
    properties (Access = public)
        
        questionVector; %vettore con indici delle domande rimanenti per classificazione
        stringForXlsxRead; %String che prepara xlxsRead
        codiceErrore = 0; %errore sulla lettura del dataset
        custom = 0; %serve a determinare se è stato scelto un dataset custom
        centroidi; %contiene la matrice dei centroidi
        numDomanda; %contiene la prossima domanda da fare all'utente
        
        DSfileName = 'SmallDataset.xlsx'; %tiene traccia del nome del dataset letto
        TrainingSet = 0; %vettore con campioni del train set
        bestClassesTrain; %label attribuite al train set dal modello NN
        TestingSet  = 0; %vettore coi campioni del test set
        bestClassesTest; %label attribuite al test set dal modello NN
        %elbowFlag; %flag per l'elbow method
        
        bestClassificationModel; % miglior modello di classificazione ad albero
        answereVector; % vettore che conterrà le risposte dell'utente alle domande
        questionIndex  = 1; % indice che comunica a quale domanda si è arrivati aprte da 1 e scorre questionVector
        questionMaxNumber = 0; % indica il numero totale di domande per classificare un nuovo utente
    end
    
    methods
        function questionNumber = questionFinder(app)
        %QUESTIONFINDER trova la domanda successiva
        %   la funzione ritorna il numero della feature della domanda in posizione index di questionVector
        %   e aggiusta la UI per mostrare il corretto feedback e cosi che l'utente possa rispondere
        %   la funzione gestisce domande a due o tre risposte
            
            question = app.questionVector(app.questionIndex);
            if (app.meanings(question,3)==-1)
                app.NOButton.Visible   =  'on';
                app.NOButton.Enable    =  'on';
                app.BOHButton.Visible  =  'off';
                app.BOHButton.Enable   =  'off';
                app.YESButton.Visible  =  'on';
                app.YESButton.Enable   =  'on';
                app.NOButton.Text  = app.answeres(question,1);
                app.YESButton.Text  = app.answeres(question,2);
                app.BOHButton.Text = app.answeres(question,3);
                app.QuestionLabel.Text = app.questions(question);
                
            else
                app.NOButton.Visible   =  'on';
                app.NOButton.Enable    =  'on';
                app.NOButton.Text      =  'on';
                app.BOHButton.Visible  =  'on';
                app.BOHButton.Enable   =  'on';
                app.YESButton.Visible  =  'on';
                app.YESButton.Enable   =  'on';
                app.NOButton.Text  = app.answeres(question,1);
                app.YESButton.Text  = app.answeres(question,2);
                app.BOHButton.Text = app.answeres(question,3);
                app.QuestionLabel.Text = app.questions(question);
            end
            questionNumber = question;
            app.counterQuestionLabel.Text = strcat(int2str(app.questionIndex),'/',int2str(app.questionMaxNumber));
        end
        
        function booleanResult = questionResolver(app,userAnswer)
        %QUESTIONRESOLVER registra la risposta dell'utente
        %   conoscendo il numero della domanda dalla funzione precedente, see also QUESTIONFINDER, questa funzione registra la scelta
        %   dell'utente e procede far evolvere index per la domanda successiva
        %   Input Argument:
        %       USERANSWER      intero che rappresenta la scelta dell'utente per una feature per il dataset
        %                       il significato è contenuto nella matrice MEANING                       
            app.answereVector(app.questionIndex) = userAnswer;
            
            if (app.questionIndex==app.questionMaxNumber)
                
                winnerCluster = predict(app.bestClassificationModel,app.answereVector);%calcolo cluster di appartenenza
                
                app.QuestionLabel.Text = strcat('you have been classified in cluster #',int2str(winnerCluster)) ;
                app.QuestionLabel.Visible  = 'on';
                
                app.NOButton.Visible   =  'off';
                app.NOButton.Enable    =  'off';
                app.BOHButton.Visible  =  'off';
                app.BOHButton.Enable   =  'off';
                app.YESButton.Visible  =  'off';
                app.YESButton.Enable   =  'off';
                
                booleanResult = false;
                
                saveWinnerCluster(app,app.centroidi(:,winnerCluster));
            else
                app.questionIndex = app.questionIndex+1; %aumento indice per domanda successiva
                booleanResult = true;
            end
        end
        
        function restoreDefaultState(app)
        %RESTOREDEFAULTSTATE rimodifica in caso di errore gli elementi della gui
        %   la funzione si occupa, in caso di errore o di reset, di ristabilire gli elementi della GUI ed alcune variabili,
        %   in modo che l'utente possa far partire nuovamente una procedura corretta
            app.ErrorLabel.Visible        = 'off';
            app.ErroreFinaleLabel.Visible = 'off';
            
            app.FeatureSelButton.Enable         = 'off';
            app.FeatureSelButton.Visible        = 'off';
            app.FeatureSelLabel.Visible         = 'off';
            app.FeatureSelNumberLabel.Visible   = 'off';
            app.ImportantFeatureDeleted.Visible = 'off';
            
            app.NOButton.Visible   =  'off';
            app.NOButton.Enable    =  'off';
            app.BOHButton.Visible  =  'off';
            app.BOHButton.Enable   =  'off';
            app.YESButton.Visible  =  'off';
            app.YESButton.Enable   =  'off';
            
            app.counterQuestionLabel.Visible = 'off';
            app.QuestionLabel.Text = 'You need to choose a dataset, train the net ';
            app.QuestionLabel.Visible = 'on';
            app.QuestionLabel2.Text = 'and perform feature selection first';
            app.QuestionLabel2.Visible = 'on';
            
            app.questionIndex = 1;
            app.answereVector = 0;
            app.numDomanda    = 0;
        end      
    end

    methods (Access = private)

        % Code that executes after component creation
        function onCreate(app)
        %ONCREATE execute code after the app si created
            app.DefaultDSButton.Tag = 'Default';
            app.FindDSButton.Tag    = 'Find';
            app.ShowDataset.Visible = 'off';
            
            restoreDefaultState(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        %STARTBUTTONPUSHED callback del listener del bottone startButton
        %   in questa funzione si sviluppa il cuore della prima parte del progetto
        %   qui viene generata la rete neurale e prodotte le label per il dataset
            restoreDefaultState(app);
            
            roofCluster = 15; %numero massimo di cluster per il progetto settato a 15
            errors = zeros(1,roofCluster); %vettore che conterrà le precisioni delle reti analizzate
            precisionBestNet = 50; %precisione della migliore rete
            dimentionBestNet = [0 0]; %dimensione della miglire rete a fine processo
            count = roofCluster; %
            elbowFlag = true;
            
            if (strcmp(app.SelectaDatasetButtonGroup.SelectedObject.Tag,'Default'))
                %questo primo controllo discrimina tra dataset di default e dataset scelto dall'utente
                app.custom = 0;
                
                app.QuestionLabel.Text  = 'You need to choose a dataset, train the net';
                app.QuestionLabel2.Text = 'and perform feature selection first';
                app.QuestionLabel2.Visible       = 'on';
                app.counterQuestionLabel.Visible = 'off';
                app.DSfileName = 'SmallDataset.xlsx';
                
            elseif(strcmp(app.SelectaDatasetButtonGroup.SelectedObject.Tag,'Find'))
                app.custom = 1; %le domande non sono ancora disponibili
            else
                errorCode = -1; %non dovrebbe essere possibile accedere a questo branch
            end
            
            [dataset, errorCode] =  loadDataset(app,app.DSfileName);
            
            if errorCode == 0 %se la lettura è andata a buon fine procedo con l'analisi degli errori
                errorCode = errorChecker(app,dataset);
            else
                dataset = 0;
            end
            
            if errorHandler(app,errorCode) %errorHandler torna true se errorCode = 0
                
                app.StartButton.Enable = 'off';
                app.ErrorLabel.Text = 'Wait for the tool to end...';
                app.ErrorLabel.Visible = 'on';
                
                [app.TrainingSet, app.TestingSet] = splitCrossVal(app,dataset);
                
                x = app.TrainingSet';
                t = app.TestingSet';
                
                for clusterNumber=2:roofCluster %ciclo sul numero dei cluster da 2 a roof
                    
                    app.ErrorLabel.Text = strcat('Wait for the tool to end...',num2str(count-clusterNumber+1));
                    
                    errorThreshold = 50;%errore per stabilire tra le reti a n neuroni la migliore
                    
                    for numA=1:clusterNumber
                    %genero le dimensioni della rete: voglio configurazioni rettangolari
                    %le cui dimensioni moltiplicate diano il numero dei cluster
                        if mod(clusterNumber,numA)==0 %esegue il resto della divisione
                            
                            %%GENERO LA RETE
                            numB = clusterNumber/numA;
                            [numFeature , ~] = size(x);
                            
                            net = selforgmap([numA numB]);%genero la net
                            
                            net.trainParam.lr =  0.06;
                            net.trainParam.epochs = 200;
                            
                            net = train(net,x);%training della rete sul dataset di training x
                            
                            y = net(x);
                            classesTrain = vec2ind(y); %classes contiene le label per i campioni in x
                            
                            matriceCentroidi = computeCentroidMatrix(app,numFeature,clusterNumber,classesTrain,x);
                            
                            %TEST DELLE PRESTAZIONI
                            k = net(t);
                            classesTest = vec2ind(k);
                            
                            hitRate = zeros(1,length(classesTest));
                            for i=1:length(classesTest)
                                errorVector = matriceCentroidi(:,classesTest(i))-t(:,i);
                                goodResult = sum(errorVector(:)==0);
                                hitRate(:,i) = (goodResult/numFeature)*100;
                            end
                            hitRateComplessivo = mean(hitRate);
                            
                            if hitRateComplessivo>errorThreshold %se la rete è la migliore(sin ora) per questo n
                                errorThreshold=hitRateComplessivo; %aggiorno il threshold
                                errors(clusterNumber) = 100-hitRateComplessivo; %computo errore
                            end
                        end
                    end

                    elbowFlag = elbowMethod(app,clusterNumber,errors,elbowFlag); %gestisce elbow
                    if elbowFlag %se non si è passato l'elbow
                        if errors(clusterNumber)<(100-precisionBestNet)%se la rete è la migliore in assoluto
                            precisionBestNet=hitRateComplessivo; %aggiorno la miglior precisione
                            bestMatriceCentroidi = matriceCentroidi; %aggiorno i migliori centroidi
                            app.bestClassesTrain = classesTrain; %salvo le label per train
                            app.bestClassesTest  = classesTest; %salvo le label per test
                            bestNet = net; %salvo la migliori net
                            dimentionBestNet = [numA numB]; %aggiorno le dimensioni
                        end
                    end
                end
                
                bestNetNumber = dimentionBestNet(1,1)*dimentionBestNet(1,2);
                app.centroidi = bestMatriceCentroidi;
                
                %%AGGIORNAMENTO GUI
                app.ResultGraph.XGrid = 'on';
                app.ResultGraph.Box   = 'on';
                
                app.RisultatiLabel.Text = strcat('the tool found...',num2str(bestNetNumber),' cluster');
                app.ErroreFinaleLabel.Text = strcat('error is .. ',num2str(errors(dimentionBestNet(1,1)*dimentionBestNet(1,2))),'%');
                
                Xes = 2:length(errors);
                plot(app.ResultGraph,Xes,errors(Xes),'-o','MarkerIndices',bestNetNumber-1);%plot del grafico degli errori
                
                app.StartButton.Enable        = 'on';
                app.ErrorLabel.Visible        = 'off';
                app.FeatureSelButton.Enable   = 'on';
                app.ErroreFinaleLabel.Visible = 'on';
                app.StartButton.Text          = 'ReStart';
                app.FeatureSelButton.Enable  = 'on';
                app.FeatureSelButton.Visible = 'on';
                app.TabGroup.SelectedTab = app.RisultatiTab;
            end
        end

        % Button pushed function: NOButton
        function NOButtonPushed(app, event)
        %NOBUTTONPUSHED callback del listener del bottone NOButton
        %   premere il bottone significa rispondere a una domanda per la classificazione finale dell'utente
        %   il primo if comunica il valore della scelta dell'utente e controlla di non essere arrivati all'ultima feature
        %   la seconda funzione, se necessario, prepara la UI per la prossima domanda
        %   see also BOHBUTTONPUSHED, YESBUTTONPUSHED
            if(questionResolver(app,1))
                questionFinder(app);
            end
        end

        % Button pushed function: BOHButton
        function BOHButtonPushed(app, event)
            %BOHBUTTONPUSHED callback del listener del bottone BOHButton
            %   premere il bottone significa rispondere a una domanda per la classificazione finale dell'utente
            %   il primo if comunica il valore della scelta dell'utente e controlla di non essere arrivati all'ultima feature
            %   la seconda funzione, se necessario, prepara la UI per la prossima domanda
            %   see also NOBUTTONPUSHED, YESBUTTONPUSHED
            if(questionResolver(app,3))
                questionFinder(app);
            end
        end

        % Button pushed function: YESButton
        function YESButtonPushed(app, event)
            %YESBUTTONPUSHED callback del listener del bottone YESButton
            %   premere il bottone significa rispondere a una domanda per la classificazione finale dell'utente
            %   il primo if comunica il valore della scelta dell'utente e controlla di non essere arrivati all'ultima feature
            %   la seconda funzione, se necessario, prepara la UI per la prossima domanda
            %   see also NOBUTTONPUSHED, BOHBUTTONPUSHED
            if(questionResolver(app,2))
                questionFinder(app);
            end
        end

        % Button pushed function: FeatureSelButton
        function FeatureSelButtonPushed(app, event)
            %FEATURESELBUTTONPUSHED callback del listener del bottone FeatureSelButton
            %   premere il pulsante significa iniziare la routine di feature selection e la generazione dei modelli ad albero
            %   per la classificazione di un nuovo utente rispetto al dataset predefinito.
            %   inizialmente vengono cercate ed eliminate le feature dal potere predittivo nullo
            %   a seguito di questa operazione viene controllato che la precisione del modello non sia stata intaccata
            %   vengono poi generati modelli ad albero eliminando la feature con potere predittivo minore tra le rimaste
            %   il modello migliore viene salvato e utilizzato successivamente per la predizione del nuovo utente
            [~, numFeature] = size(app.TrainingSet);
            
            app.questionVector = 1:numFeature;
            
            tree = fitctree(app.TrainingSet,app.bestClassesTrain'); %genero il modello ad albero del mio set
            imp = predictorImportance(tree); %caloclo l'importanza delle feature
            
            %%ELIMINAZIONE FEATURE INUTILI
            L1 = loss(tree,app.TestingSet,app.bestClassesTest);
            txtImportFeature = '';
            [backUpTrain, backUpTest] = deleteUselessFeature(app,app.TrainingSet,app.TestingSet,numFeature,imp);
            tree = fitctree(backUpTrain,app.bestClassesTrain');
            L2 = loss(tree,backUpTest,app.bestClassesTest);
            for i=1:length(app.questionVector)
                txtImportFeature = strcat(txtImportFeature,num2str(app.questionVector(i)),', ');
            end
            
            if (L1==L2) %controllo che la precisione dell'albero non sia effettivamente diminuita con il nuovo modello
                app.FeatureSelNumberLabel.Text    = txtImportFeature;
                app.FeatureSelNumberLabel.Visible = 'on';
                app.FeatureSelLabel.Visible       = 'on';
            else %questo caso non dovrebbe mai verificarsi
                restoreDefaultState(app);
            end
            
            app.ErroreFinaleLabel.Visible = 'off';
            
            %%INIZIO FEATURE SELECTION
            deletedFeatureString = "Feature deleted are, in order: ";
            counter = 1;
            bestLoss = L2;
            domandeRimanenti = app.questionVector;
            app.bestClassificationModel = tree;
            bestModelFeatureNumber = counter;
            deletedFeatureStringBestModel = "no other feature can be deleted";
            
            while numFeature>1
                tree = fitctree(backUpTrain,app.bestClassesTrain');
                L = loss(tree,backUpTest,app.bestClassesTest); 
                error(counter) = L*100;
                if (L<=bestLoss)
                    bestLoss = L;
                    app.questionVector = domandeRimanenti;
                    app.bestClassificationModel = tree;
                    bestModelFeatureNumber = counter;
                    deletedFeatureStringBestModel = deletedFeatureString;
                end
                [~, numFeature] = size(backUpTrain);
                importance = predictorImportance(tree);
                [~, index] = min(importance);
                deletedFeatureString = strcat(deletedFeatureString,num2str(domandeRimanenti(index)),", ");
                backUpTrain(:,index) = [];
                backUpTest(:,index) = [];
                domandeRimanenti(index) = [];
                counter=counter+1;
            end
            
            %%MODIFICHE UI
            title(app.ResultGraph, 'FeatureSel Graph');
            xlabel(app.ResultGraph, 'Number of feature deleted');
            ylabel(app.ResultGraph, 'Error');
            app.RisultatiLabel.Text = 'Feature Selection Results';
            
            app.ImportantFeatureDeleted.Text = deletedFeatureStringBestModel;
            app.ImportantFeatureDeleted.Visible = 'on';
            
            Xes = 0:length(error)-1;
            plot(app.ResultGraph,Xes,error(Xes+1),'-o','MarkerIndices',bestModelFeatureNumber); %plot sul grafico
            
            app.FeatureSelButton.Enable = 'off';
            
            if(app.custom == 0) %se il dataset è default inizio routine per la classificazione
                app.questionMaxNumber = length(app.questionVector);
                app.QuestionLabel2.Visible       = 'off';
                app.counterQuestionLabel.Visible = 'on';
                
                questionFinder(app);
            else %altrimenti modifico l'UI per comunicarlo all'utente
                app.QuestionLabel2.Visible       = 'off';
                app.counterQuestionLabel.Visible = 'off';
                app.QuestionLabel.Text           = 'this function is not available for custom dataset';
            end
        end

        % Selection changed function: SelectaDatasetButtonGroup
        function SelectaDatasetButtonGroupSelectionChanged(app, event)
            %SELECTDATASETBUTTONGROUPSELEXRIONXHANFED callback del listener del buttongroup SelectaDatasetButtonGroup
            %   il button group controlla se l'utente ha intenzione di usare un dataset proprietario o quello di default
            %   a seconda della scelta vengono modificati alcuni elementi della GUI e la proprietà DSfileName
            selectedButton = app.SelectaDatasetButtonGroup.SelectedObject;
            
            if (strcmp(selectedButton.Tag,'Default'))
                app.ErrorLabel.Visible  = 'off';
                app.StartButton.Enable  = 'on';
                app.ShowDataset.Visible = 'off';
                app.selectCustomDSButton.Enable = 'off';
                app.selectCustomDSLabel.Enable = 'off';
                
                app.DSfileName = 'SmallDataset.xlsx';
            elseif (strcmp(selectedButton.Tag,'Find'))
                
                %GUI Elements
                app.ErrorLabel.Visible = 'off';
                app.StartButton.Enable = 'on';
                app.ShowDataset.Visible = 'off';
                app.selectCustomDSButton.Enable = 'on';
                app.selectCustomDSLabel.Enable = 'on';
                
                app.DSfileName = 0;
            end
        end

        % Button pushed function: selectCustomDSButton
        function selectCustomDSButtonPushed(app, event)
            %SELECTCUSTOMDSBUTTONPUSHED callback del listener del bottone selectCustomDSButton
            %  premere il bottone permette all'utente di accedere all'interfaccia di scelta di un dataset
            %  verranno proposti solo file exel formato xlsx. nel caso l'utente chiuda la finestra
            %  senza effettuare una scelta viene mostrato un messaggio di errore
            [FileName,PathName] = uigetfile('*.xlsx','Select your Excel file');
            xlsreadstring = strcat(PathName,FileName);
            if FileName == 0 %c'è stato un errore
                app.DSfileName = 0;
                app.ShowDataset.Text = strcat('something went wrong. Choose again');
                app.ShowDataset.Visible = 'on';
                app.StartButton.Enable = 'off';
            else
                app.ShowDataset.Text = strcat('you choose: "',FileName,'" as dataset');
                app.ShowDataset.Visible = 'on';
                app.DSfileName = xlsreadstring;
                app.StartButton.Enable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MyPrivacyPersona';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.StartButton = uibutton(app.StartTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ErrorLabel = uilabel(app.StartTab);
            app.ErrorLabel.Text = 'error: this is a placeholder error message it will be sobstituted programmaticaly';
            app.SelectaDatasetButtonGroup = uibuttongroup(app.StartTab);
            app.SelectaDatasetButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectaDatasetButtonGroupSelectionChanged, true);
            app.SelectaDatasetButtonGroup.Title = 'Select a Dataset';
            app.DefaultDSButton = uiradiobutton(app.SelectaDatasetButtonGroup);
            app.DefaultDSButton.Text = 'Use default Dataset';
            app.DefaultDSButton.Value = true;
            app.FindDSButton = uiradiobutton(app.SelectaDatasetButtonGroup);
            app.FindDSButton.Text = 'Find a custom Dataset';
            app.ShowDataset = uilabel(app.SelectaDatasetButtonGroup);
            app.ShowDataset.Text = 'here we will show the selected DS name';
            app.selectCustomDSButton = uibutton(app.SelectaDatasetButtonGroup, 'push');
            app.selectCustomDSButton.ButtonPushedFcn = createCallbackFcn(app, @selectCustomDSButtonPushed, true);
            app.selectCustomDSButton.Enable = 'off';
            app.selectCustomDSButton.Text = 'Find';
            app.selectCustomDSLabel = uilabel(app.SelectaDatasetButtonGroup);
            app.selectCustomDSLabel.Enable = 'off';
            app.selectCustomDSLabel.Text = 'Select a custom Dataset';
            app.WelcomeLabel = uilabel(app.StartTab);
            app.WelcomeLabel.Text = 'Welcome in MyPrivacyPersona';
            app.RisultatiTab = uitab(app.TabGroup);
            app.RisultatiTab.Title = 'Result';
            app.RisultatiLabel = uilabel(app.RisultatiTab);
            app.RisultatiLabel.Text = 'You need to choose a dataset and train the net first';
            app.FeatureSelButton = uibutton(app.RisultatiTab, 'push');
            app.FeatureSelButton.ButtonPushedFcn = createCallbackFcn(app, @FeatureSelButtonPushed, true);
            app.FeatureSelButton.Enable = 'off';
            app.FeatureSelButton.Text = 'FeatureSelection';
            app.ErroreFinaleLabel = uilabel(app.RisultatiTab);
            app.ErroreFinaleLabel.Text = 'this is the label where the error will be visualized';
            app.FeatureSelLabel = uilabel(app.RisultatiTab);
            app.FeatureSelLabel.Text = 'following features have predictive power for the tree model:';
            app.FeatureSelNumberLabel = uilabel(app.RisultatiTab);
            app.FeatureSelNumberLabel.Text = 'feature with  predictive power will be seen as a list separated by commas';
            app.ResultGraph = uiaxes(app.RisultatiTab);
            title(app.ResultGraph, 'Result Graph')
            xlabel(app.ResultGraph, 'Number of clusters')
            ylabel(app.ResultGraph, 'Error')
            app.ImportantFeatureDeleted = uilabel(app.RisultatiTab);
            app.ImportantFeatureDeleted.Text = 'Here will be shown all the importan feature deleted in order from less important to more important';
            app.FindmypermissionTab = uitab(app.TabGroup);
            app.FindmypermissionTab.Title = 'Find my permission';
            app.QuestionLabel = uilabel(app.FindmypermissionTab);
            app.QuestionLabel.Text = 'You need to choose a dataset, train the net ';
            app.NOButton = uibutton(app.FindmypermissionTab, 'push');
            app.NOButton.ButtonPushedFcn = createCallbackFcn(app, @NOButtonPushed, true);
            app.NOButton.Enable = 'off';
            app.NOButton.Text = 'NO';
            app.BOHButton = uibutton(app.FindmypermissionTab, 'push');
            app.BOHButton.ButtonPushedFcn = createCallbackFcn(app, @BOHButtonPushed, true);
            app.BOHButton.Enable = 'off';
            app.BOHButton.Text = 'BOH';
            app.YESButton = uibutton(app.FindmypermissionTab, 'push');
            app.YESButton.ButtonPushedFcn = createCallbackFcn(app, @YESButtonPushed, true);
            app.YESButton.Enable = 'off';
            app.YESButton.Text = 'YES';
            app.QuestionLabel2 = uilabel(app.FindmypermissionTab);
            app.QuestionLabel2.Text = 'and perform feature selection first';
            app.counterQuestionLabel = uilabel(app.FindmypermissionTab);
            app.counterQuestionLabel.Text = '11/12';
        end
    end

    methods (Access = public)

        % Construct app
        function app = myPrivacyPersona

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onCreate)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end